classdef CalcApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CorrectedCRTNResultEditField    matlab.ui.control.EditField
        CorrectedCRTNResultEditFieldLabel  matlab.ui.control.Label
        UncorrectedCRTNResultEditField  matlab.ui.control.EditField
        UncorrectedCRTNResultEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        SamplingtimeEditField           matlab.ui.control.NumericEditField
        SamplingtimeEditFieldLabel      matlab.ui.control.Label
        SpeedMphEditField               matlab.ui.control.NumericEditField
        SpeedMphEditFieldLabel          matlab.ui.control.Label
        AngleofRoadEditField            matlab.ui.control.NumericEditField
        AngleofRoadEditFieldLabel       matlab.ui.control.Label
        VelocityKmhEditField            matlab.ui.control.NumericEditField
        VelocityKmhEditFieldLabel       matlab.ui.control.Label
        TotalHGVsEditField              matlab.ui.control.NumericEditField
        TotalHGVsEditFieldLabel         matlab.ui.control.Label
        NumberofminutesObservationEditField  matlab.ui.control.NumericEditField
        NumberofminutesObservationEditFieldLabel  matlab.ui.control.Label
        TotalVehiclesObservedEditField  matlab.ui.control.NumericEditField
        TotalVehiclesObservedEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)

        function results = totalVehicles(app,vehicleCount,observationTime)
            results=(60/observationTime)*vehicleCount;
            
        end
        
        function results = CalculateCRTN(app,vehicleCount,observationTime)
            
            results=42.2+10*log10(totalVehicles(app,vehicleCount,observationTime));
        end

        
        function results = percHgv(app,hgvCount,vehicleCount)

            results=(hgvCount/vehicleCount)*100;
        end
        
        function results = correctionCRTN(app,velocity,hgvCount,vehicleCount)
            results=(33*log10(velocity+40+(500/velocity)))+(10*log10(1+((5*percHgv(app,hgvCount,vehicleCount))/velocity)))-68.8;
        end
        
        function results = angleCorrection(app,roadAngle)

            results=(10*log10((roadAngle/180)));
            
        end

        function results = correctedCRTN(app,velocity,hgvCount,vehicleCount,observationTime);

            results= (correctionCRTN(app,velocity,hgvCount,vehicleCount))+CalculateCRTN(app,vehicleCount,observationTime);
            
        end

        
        function results = angCorr(app,roadAngle)
            results =log10(roadAngle/180);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            samplingTime=app.SamplingtimeEditField.Value;
            roadAngle=app.AngleofRoadEditField.Value;
            milesHour=app.SpeedMphEditField.Value;
            hgvCount=app.TotalHGVsEditField.Value;
            observationTime=app.NumberofminutesObservationEditField.Value;
            vehicleCount=app.TotalVehiclesObservedEditField.Value;

            velocity=1.691*milesHour;
            app.VelocityKmhEditField.Value=velocity;


            resultcalc=CalculateCRTN(app,vehicleCount,observationTime);
            app.UncorrectedCRTNResultEditField.Value=compose("%1.3f",resultcalc);

            resultcalccorr=correctedCRTN(app,velocity,hgvCount,vehicleCount,observationTime);
            app.CorrectedCRTNResultEditField.Value=compose("%1.3f",resultcalccorr);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TotalVehiclesObservedEditFieldLabel = uilabel(app.UIFigure);
            app.TotalVehiclesObservedEditFieldLabel.Text = 'Total Vehicles Observed';
            app.TotalVehiclesObservedEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalVehiclesObservedEditField.Limits = [0 Inf];
            app.NumberofminutesObservationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofminutesObservationEditFieldLabel.Text = 'Number of minutes Observation';
            app.NumberofminutesObservationEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofminutesObservationEditField.Limits = [0 Inf];
            app.TotalHGVsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalHGVsEditFieldLabel.Text = 'Total HGVs';
            app.TotalHGVsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalHGVsEditField.Limits = [0 Inf];
            app.VelocityKmhEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityKmhEditFieldLabel.Text = 'Velocity (Km/h)';
            app.VelocityKmhEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityKmhEditField.AllowEmpty = 'on';
            app.VelocityKmhEditField.Editable = 'off';
            app.VelocityKmhEditField.Value = [];
            app.AngleofRoadEditFieldLabel = uilabel(app.UIFigure);
            app.AngleofRoadEditFieldLabel.Text = 'Angle of Road';
            app.AngleofRoadEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleofRoadEditField.Limits = [0 360];
            app.AngleofRoadEditField.Value = 180;
            app.SpeedMphEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedMphEditFieldLabel.Text = 'Speed (Mph)';
            app.SpeedMphEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedMphEditField.Limits = [0 Inf];
            app.SamplingtimeEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingtimeEditFieldLabel.Text = 'Sampling time';
            app.SamplingtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingtimeEditField.Limits = [0 Inf];
            app.SamplingtimeEditField.Editable = 'off';
            app.SamplingtimeEditField.Value = 125;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UncorrectedCRTNResultEditFieldLabel = uilabel(app.UIFigure);
            app.UncorrectedCRTNResultEditFieldLabel.Text = 'Uncorrected CRTN Result :';
            app.UncorrectedCRTNResultEditField = uieditfield(app.UIFigure, 'text');
            app.UncorrectedCRTNResultEditField.Editable = 'off';
            app.UncorrectedCRTNResultEditField.Enable = 'off';
            app.CorrectedCRTNResultEditFieldLabel = uilabel(app.UIFigure);
            app.CorrectedCRTNResultEditFieldLabel.Text = 'Corrected CRTN Result :';
            app.CorrectedCRTNResultEditField = uieditfield(app.UIFigure, 'text');
            app.CorrectedCRTNResultEditField.Editable = 'off';
            app.CorrectedCRTNResultEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalcApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end