classdef Week_4_RT60_Calculator_Advanced < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AverageAbsorptionEditField    matlab.ui.control.NumericEditField
        AverageAbsorptionEditFieldLabel  matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        HzEditField_12                matlab.ui.control.NumericEditField
        HzEditField_12Label           matlab.ui.control.Label
        HzEditField_11                matlab.ui.control.NumericEditField
        HzEditField_11Label           matlab.ui.control.Label
        HzEditField_10                matlab.ui.control.NumericEditField
        HzEditField_10Label           matlab.ui.control.Label
        HzEditField_9                 matlab.ui.control.NumericEditField
        HzEditField_9Label            matlab.ui.control.Label
        HzEditField_8                 matlab.ui.control.NumericEditField
        HzEditField_8Label            matlab.ui.control.Label
        HzEditField_7                 matlab.ui.control.NumericEditField
        HzEditField_7Label            matlab.ui.control.Label
        EyringNorrisEquationLabel     matlab.ui.control.Label
        SabineEquationLabel           matlab.ui.control.Label
        RoomMaterialsLabel            matlab.ui.control.Label
        RoomDimensionsLabel           matlab.ui.control.Label
        RoomReverberationCalculatorLabel  matlab.ui.control.Label
        HzEditField_4                 matlab.ui.control.NumericEditField
        HzEditField_4Label            matlab.ui.control.Label
        HzEditField_6                 matlab.ui.control.NumericEditField
        HzEditField_6Label            matlab.ui.control.Label
        HzEditField_3                 matlab.ui.control.NumericEditField
        HzEditField_3Label            matlab.ui.control.Label
        HzEditField_5                 matlab.ui.control.NumericEditField
        HzEditField_5Label            matlab.ui.control.Label
        HzEditField_2                 matlab.ui.control.NumericEditField
        HzEditField_2Label            matlab.ui.control.Label
        HzEditField                   matlab.ui.control.NumericEditField
        HzEditFieldLabel              matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        CeilingMaterialDropDown       matlab.ui.control.DropDown
        CeilingMaterialDropDownLabel  matlab.ui.control.Label
        FloorMaterialDropDown         matlab.ui.control.DropDown
        FloorMaterialDropDownLabel    matlab.ui.control.Label
        WallMaterialDropDown          matlab.ui.control.DropDown
        WallMaterialDropDownLabel     matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Obtain user inputs from GUI
            RoomLength = app.LengthEditField.Value;
            RoomWidth = app.WidthEditField.Value;
            RoomHeight = app.HeightEditField.Value;
            
            % Calculate room volume
            Volume = RoomLength * RoomWidth * RoomHeight;
            
            % Calculate surface areas
            AreaLWall = RoomLength * RoomHeight;
            AreaRWall = RoomLength * RoomHeight;
            AreaFWall = RoomWidth * RoomHeight;
            AreaBWall = RoomWidth * RoomHeight;
            AreaFloor = RoomLength * RoomWidth;
            AreaCeiling = RoomLength * RoomWidth;
            
            TotalArea = AreaLWall + AreaRWall + AreaFWall + AreaBWall + AreaFloor + AreaCeiling;
            
            % Convert DropDown from string (text) to numbers
            MaterialWalls =str2double(app.WallMaterialDropDown.Value);
            MaterialFloor =str2double(app.FloorMaterialDropDown.Value);
            MaterialCeiling =str2double(app.CeilingMaterialDropDown.Value);
            
            % Absorption Coefficients [125Hz 250Hz 500Hz 1000Hz 2000Hz 4000Hz]
            Carpet = [0.10, 0.15, 0.25, 0.30, 0.30, 0.30];
            PaintedPlaster = [0.02, 0.02, 0.02, 0.02, 0.02, 0.02];
            PlasterCeilingTiles = [0.45, 0.70, 0.80, 0.80, 0.65, 0.45];
            Rockwool50mm = [0.15, 0.60, 0.90, 0.90, 0.90, 0.85];
            WoodBoards = [0.15, 0.20, 0.10, 0.10, 0.10, 0.10];
            
            % Turn arrays into a matrix
            Matrix = [Carpet; PaintedPlaster; PlasterCeilingTiles; Rockwool50mm; WoodBoards];
            
            % Get Coefficients based on DropDown options
            WallCoefficients = Matrix(MaterialWalls,:);
            FloorCoefficients = Matrix(MaterialFloor,:);
            CeilingCoefficients = Matrix(MaterialCeiling,:);
        
            % Calculate equivalent absorption of each surface
            SabLWall = AreaLWall * WallCoefficients;
            SabRWall = AreaRWall * WallCoefficients;
            SabFWall = AreaFWall * WallCoefficients;
            SabBWall = AreaBWall * WallCoefficients;
            SabFloor = AreaFloor * FloorCoefficients;
            SabCeiling = AreaCeiling * CeilingCoefficients;
            
            % Total absorption (A)
            TotalAbsorption = SabLWall + SabRWall + SabFWall + SabBWall + SabFloor + SabCeiling;
            
            % Average absorption
                    
            AverageAbsorption = TotalAbsorption/TotalArea;
            ScalarAverageAbsorption = mean(AverageAbsorption);
            app.AverageAbsorptionEditField.Value = ScalarAverageAbsorption;
            
            % Equation lamps activiation
            if ScalarAverageAbsorption > 0.25
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'green';
            else
                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'red';
            end
            
            
            % Sabine equation
            
            Sabine = (0.161 * Volume)./TotalAbsorption;
            
            % Display Sabine in GUI
            
            app.HzEditField.Value = Sabine(1);
            app.HzEditField_2.Value = Sabine(2);
            app.HzEditField_3.Value = Sabine(3);
            app.HzEditField_4.Value = Sabine(4);
            app.HzEditField_5.Value = Sabine(5);
            app.HzEditField_6.Value = Sabine(6);
            
            % Eyring equation
            
                       
            Eyring = (0.161 * Volume)./(-1*TotalArea*log(1-AverageAbsorption));
            
            % Display Eyring in GUI
            
            app.HzEditField_7.Value = Eyring(1);
            app.HzEditField_8.Value = Eyring(2);
            app.HzEditField_9.Value = Eyring(3);
            app.HzEditField_10.Value = Eyring(4);
            app.HzEditField_11.Value = Eyring(5);
            app.HzEditField_12.Value = Eyring(6);
            
            % Display RT60s in plot
            x=[125,250,500,1000,2000,4000];
            plot(app.UIAxes,x,Sabine,'DisplayName','Sabine')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,Eyring)
            hold(app.UIAxes,'off')
            legend(app.UIAxes, 'Sabine Equation','Eyring-Norris Equation')
            
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reverberation Time')
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, 'RT60 [s]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [100 5000];
            app.UIAxes.XTick = [125 250 500 1000 2000 4000];
            app.UIAxes.XScale = 'log';
            app.UIAxes.XTickLabel = {'125'; '250'; '500'; '1000'; '2000'; '4000'};
            app.LengthEditFieldLabel = uilabel(app.UIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.Value = 1;
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField.Value = 1;
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.Value = 1;
            app.WallMaterialDropDownLabel = uilabel(app.UIFigure);
            app.WallMaterialDropDownLabel.Text = 'Wall Material';
            app.WallMaterialDropDown = uidropdown(app.UIFigure);
            app.WallMaterialDropDown.Items = {'Carpet', 'Plaster', 'Roof Tiles', 'Rockwool', 'Wooden Boards'};
            app.WallMaterialDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.WallMaterialDropDown.Value = '1';
            app.FloorMaterialDropDownLabel = uilabel(app.UIFigure);
            app.FloorMaterialDropDownLabel.Text = 'Floor Material';
            app.FloorMaterialDropDown = uidropdown(app.UIFigure);
            app.FloorMaterialDropDown.Items = {'Carpet', 'Plaster', 'Roof Tiles', 'Rockwool', 'Wooden Boards'};
            app.FloorMaterialDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.FloorMaterialDropDown.Value = '1';
            app.CeilingMaterialDropDownLabel = uilabel(app.UIFigure);
            app.CeilingMaterialDropDownLabel.Text = 'Ceiling Material';
            app.CeilingMaterialDropDown = uidropdown(app.UIFigure);
            app.CeilingMaterialDropDown.Items = {'Carpet', 'Plaster', 'Roof Tiles', 'Rockwool', 'Wooden Boards'};
            app.CeilingMaterialDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.CeilingMaterialDropDown.Value = '1';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '125Hz';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_2Label = uilabel(app.UIFigure);
            app.HzEditField_2Label.Text = '250Hz';
            app.HzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_5Label = uilabel(app.UIFigure);
            app.HzEditField_5Label.Text = '2000Hz';
            app.HzEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3Label = uilabel(app.UIFigure);
            app.HzEditField_3Label.Text = '500Hz';
            app.HzEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_6Label = uilabel(app.UIFigure);
            app.HzEditField_6Label.Text = '4000Hz';
            app.HzEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_4Label = uilabel(app.UIFigure);
            app.HzEditField_4Label.Text = '1000Hz';
            app.HzEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.RoomReverberationCalculatorLabel = uilabel(app.UIFigure);
            app.RoomReverberationCalculatorLabel.Text = 'Room Reverberation Calculator';
            app.RoomDimensionsLabel = uilabel(app.UIFigure);
            app.RoomDimensionsLabel.Text = 'Room Dimensions';
            app.RoomMaterialsLabel = uilabel(app.UIFigure);
            app.RoomMaterialsLabel.Text = 'Room Materials';
            app.SabineEquationLabel = uilabel(app.UIFigure);
            app.SabineEquationLabel.Text = 'Sabine Equation';
            app.EyringNorrisEquationLabel = uilabel(app.UIFigure);
            app.EyringNorrisEquationLabel.Text = 'Eyring-Norris Equation';
            app.HzEditField_7Label = uilabel(app.UIFigure);
            app.HzEditField_7Label.Text = '125Hz';
            app.HzEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_8Label = uilabel(app.UIFigure);
            app.HzEditField_8Label.Text = '250Hz';
            app.HzEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_9Label = uilabel(app.UIFigure);
            app.HzEditField_9Label.Text = '2000Hz';
            app.HzEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_10Label = uilabel(app.UIFigure);
            app.HzEditField_10Label.Text = '500Hz';
            app.HzEditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_11Label = uilabel(app.UIFigure);
            app.HzEditField_11Label.Text = '4000Hz';
            app.HzEditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_12Label = uilabel(app.UIFigure);
            app.HzEditField_12Label.Text = '1000Hz';
            app.HzEditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.AverageAbsorptionEditFieldLabel = uilabel(app.UIFigure);
            app.AverageAbsorptionEditFieldLabel.Text = 'Average Absorption';
            app.AverageAbsorptionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Week_4_RT60_Calculator_Advanced

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end